classdef bugsplat_angles_rev3_1_kml < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        tf_recalculateCheckBox          matlab.ui.control.CheckBox
        ServerStatusonZDriveCheckBox    matlab.ui.control.CheckBox
        TextArea_RandFolder             matlab.ui.control.TextArea
        TextArea_Multifolder            matlab.ui.control.TextArea
        BugSplatAnglesNoAnglesKMLLabel  matlab.ui.control.Label
        ParpoolWorkersEditField         matlab.ui.control.NumericEditField
        ParpoolWorkersEditFieldLabel    matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Button_SelectSimFolder          matlab.ui.control.Button
        ComputationSelectButtonGroup    matlab.ui.container.ButtonGroup
        SerialButton                    matlab.ui.control.ToggleButton
        ParallelButton                  matlab.ui.control.ToggleButton
        Rev31Label                      matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        
        function check_comp(app)
            if app.ParallelButton.Value==1
                app.ParpoolWorkersEditField.Visible='on';
                app.ParpoolWorkersEditFieldLabel.Visible='on';
            elseif app.SerialButton.Value==1
                app.ParpoolWorkersEditField.Visible='off';
                app.ParpoolWorkersEditFieldLabel.Visible='off';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            check_comp(app)
            
            % %             %%%%%%%%%Find the number of cores
            % %             max_cores=feature('numcores');
            % %             app.ParpoolWorkersEditField.Limits=[1 max_cores];
            % %             app.ParpoolWorkersEditField.Value=max_cores;
            
            
            %%%%%%%%%To be safe, the number of Workers based off of 2GB rule.
            [workers,max_cores]=recommend_num_workers(app);
            app.ParpoolWorkersEditField.Limits=[1 max_cores];
            app.ParpoolWorkersEditField.Value=workers;
        end

        % Button pushed function: Button_SelectSimFolder
        function Button_SelectSimFolderPushed(app, event)
            clc;
            rev_folder=uigetdir('Z:\MATLAB');
            cd(rev_folder)
            pause(0.1)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Parallel Flag
            if app.ParallelButton.Value==1
                parallel_flag=1; %0 --> serial, 1 --> parallel
            elseif app.SerialButton.Value==1
                parallel_flag=0; %This way we can see the progress bar.
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%tf_server_status
            if app.ServerStatusonZDriveCheckBox.Value==1
                tf_server_status=1
            else
                tf_server_status=0
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%tf_recalculate
            if app.tf_recalculateCheckBox.Value==1
                tf_recalculate=1
            else
                tf_recalculate=0
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

 
            disp_progress(app,strcat(rev_folder,'--> Starting Simulation'))
             %%%wrapper_bugsplat_rev3(app,rev_folder,parallel_flag,tf_server_status)
             %%%wrapper_bugsplat_rev4(app,rev_folder,parallel_flag,tf_server_status)
              %%%%wrapper_bugsplat_rev5(app,rev_folder,parallel_flag,tf_server_status)
              %%%%wrapper_bugsplat_rev6(app,rev_folder,parallel_flag,tf_server_status,tf_recalculate)
              %%%wrapper_bugsplat_rev7_kml_output(app,rev_folder,parallel_flag,tf_server_status,tf_recalculate)
              wrapper_bugsplat_rev8_kml_output(app,rev_folder,parallel_flag,tf_server_status,tf_recalculate)

            
            %%%%%%%%%%Consider Adding the Email Notification if we can figure out the port error
        end

        % Selection changed function: ComputationSelectButtonGroup
        function ComputationSelectButtonGroupSelectionChanged(app, event)
            check_comp(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Rev31Label = uilabel(app.UIFigure);
            app.Rev31Label.Text = 'Rev 3.1';
            app.ComputationSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.ComputationSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ComputationSelectButtonGroupSelectionChanged, true);
            app.ComputationSelectButtonGroup.Title = 'Computation Select';
            app.ParallelButton = uitogglebutton(app.ComputationSelectButtonGroup);
            app.ParallelButton.Text = 'Parallel';
            app.ParallelButton.Value = true;
            app.SerialButton = uitogglebutton(app.ComputationSelectButtonGroup);
            app.SerialButton.Text = 'Serial';
            app.Button_SelectSimFolder = uibutton(app.UIFigure, 'push');
            app.Button_SelectSimFolder.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectSimFolderPushed, true);
            app.Button_SelectSimFolder.Text = 'Select Sim Folder and Run';
            app.TextArea = uitextarea(app.UIFigure);
            app.ParpoolWorkersEditFieldLabel = uilabel(app.UIFigure);
            app.ParpoolWorkersEditFieldLabel.Text = 'Workers';
            app.ParpoolWorkersEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParpoolWorkersEditField.Limits = [1 24];
            app.ParpoolWorkersEditField.RoundFractionalValues = 'on';
            app.ParpoolWorkersEditField.Value = 12;
            app.BugSplatAnglesNoAnglesKMLLabel = uilabel(app.UIFigure);
            app.BugSplatAnglesNoAnglesKMLLabel.Text = 'Bug Splat: Angles/No Angles --> KML';
            app.TextArea_Multifolder = uitextarea(app.UIFigure);
            app.TextArea_RandFolder = uitextarea(app.UIFigure);
            app.ServerStatusonZDriveCheckBox = uicheckbox(app.UIFigure);
            app.ServerStatusonZDriveCheckBox.Text = 'Server Status on Z Drive';
            app.ServerStatusonZDriveCheckBox.Value = true;
            app.tf_recalculateCheckBox = uicheckbox(app.UIFigure);
            app.tf_recalculateCheckBox.Text = 'tf_recalculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bugsplat_angles_rev3_1_kml

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end