classdef generic_bugsplat_pathloss_rev1_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TextArea_RandFolder           matlab.ui.control.TextArea
        TextArea_Multifolder          matlab.ui.control.TextArea
        BugSplatITMPop2023Label       matlab.ui.control.Label
        ParpoolWorkersEditField       matlab.ui.control.NumericEditField
        ParpoolWorkersEditFieldLabel  matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Button_SelectSimFolder        matlab.ui.control.Button
        ComputationSelectButtonGroup  matlab.ui.container.ButtonGroup
        SerialButton                  matlab.ui.control.ToggleButton
        ParallelButton                matlab.ui.control.ToggleButton
        Rev13Census2023Label          matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        
        function check_comp(app)
            if app.ParallelButton.Value==1
                app.ParpoolWorkersEditField.Visible='on';
                app.ParpoolWorkersEditFieldLabel.Visible='on';
            elseif app.SerialButton.Value==1
                app.ParpoolWorkersEditField.Visible='off';
                app.ParpoolWorkersEditFieldLabel.Visible='off';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            check_comp(app)
            
            % %             %%%%%%%%%Find the number of cores
            % %             max_cores=feature('numcores');
            % %             app.ParpoolWorkersEditField.Limits=[1 max_cores];
            % %             app.ParpoolWorkersEditField.Value=max_cores;
            
            
            %%%%%%%%%To be safe, the number of Workers based off of 2GB rule.
            [workers,max_cores]=recommend_num_workers(app);
            app.ParpoolWorkersEditField.Limits=[1 max_cores];
            app.ParpoolWorkersEditField.Value=workers;
        end

        % Button pushed function: Button_SelectSimFolder
        function Button_SelectSimFolderPushed(app, event)
            clc;
            rev_folder=uigetdir('Z:\MATLAB');
            cd(rev_folder)
            pause(0.1)

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Parallel Flag
            if app.ParallelButton.Value==1
                parallel_flag=1; %0 --> serial, 1 --> parallel
            elseif app.SerialButton.Value==1
                parallel_flag=0; %This way we can see the progress bar.
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            disp_progress(app,strcat(rev_folder,'--> Starting Simulation'))

            %%%%wrapper_bugsplat_rev2(app,rev_folder,parallel_flag)
            wrapper_bugsplat_merge_folders_geoplot_pea2023_rev16(app,rev_folder,parallel_flag,workers,tf_server_status,tf_recalculate,tf_rescrap_rev_data)

            
            %%%%%%%%%%Consider Adding the Email Notification if we can figure out the port error
        end

        % Selection changed function: ComputationSelectButtonGroup
        function ComputationSelectButtonGroupSelectionChanged(app, event)
            check_comp(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Rev13Census2023Label = uilabel(app.UIFigure);
            app.Rev13Census2023Label.Text = 'Rev 1.3 Census 2023';
            app.ComputationSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.ComputationSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ComputationSelectButtonGroupSelectionChanged, true);
            app.ComputationSelectButtonGroup.Title = 'Computation Select';
            app.ParallelButton = uitogglebutton(app.ComputationSelectButtonGroup);
            app.ParallelButton.Text = 'Parallel';
            app.ParallelButton.Value = true;
            app.SerialButton = uitogglebutton(app.ComputationSelectButtonGroup);
            app.SerialButton.Text = 'Serial';
            app.Button_SelectSimFolder = uibutton(app.UIFigure, 'push');
            app.Button_SelectSimFolder.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectSimFolderPushed, true);
            app.Button_SelectSimFolder.Text = 'Select Sim Folder and Run';
            app.TextArea = uitextarea(app.UIFigure);
            app.ParpoolWorkersEditFieldLabel = uilabel(app.UIFigure);
            app.ParpoolWorkersEditFieldLabel.Text = 'Workers';
            app.ParpoolWorkersEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParpoolWorkersEditField.Limits = [1 24];
            app.ParpoolWorkersEditField.RoundFractionalValues = 'on';
            app.ParpoolWorkersEditField.Value = 12;
            app.BugSplatITMPop2023Label = uilabel(app.UIFigure);
            app.BugSplatITMPop2023Label.Text = 'Bug Splat: ITM: Pop 2023';
            app.TextArea_Multifolder = uitextarea(app.UIFigure);
            app.TextArea_RandFolder = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generic_bugsplat_pathloss_rev1_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end